classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        BiseccinTab                     matlab.ui.container.Tab
        LabelCriterioParadaBiseccion    matlab.ui.control.Label
        CriteriodeparadaLabel           matlab.ui.control.Label
        CalcularButton_2                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        NIteracionesTextArea            matlab.ui.control.TextArea
        IteracionesLabel                matlab.ui.control.Label
        XsTextArea                      matlab.ui.control.TextArea
        IntervaloSuperiorLabel          matlab.ui.control.Label
        XiTextArea                      matlab.ui.control.TextArea
        IntervaloInferiorLabel          matlab.ui.control.Label
        FxTextArea                      matlab.ui.control.TextArea
        FxLabel                         matlab.ui.control.Label
        DatosLabel                      matlab.ui.control.Label
        MtododeBiseccinLabel            matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        FalsaPosicionTab                matlab.ui.container.Tab
        CriteriodeparadaLabel_2         matlab.ui.control.Label
        DatosLabel_2                    matlab.ui.control.Label
        LabelCriterioParadaFalsaPosicion  matlab.ui.control.Label
        UITableFalsaPosicion            matlab.ui.control.Table
        ComputarFalsaPosicionButton     matlab.ui.control.Button
        NIteracionesFalsaPosicionTextArea  matlab.ui.control.TextArea
        NIteracionesTextArea_2Label_2   matlab.ui.control.Label
        XsFalsaPosicionTextArea         matlab.ui.control.TextArea
        XsLabel                         matlab.ui.control.Label
        XiFalsaPosicionTextArea         matlab.ui.control.TextArea
        XiLabel                         matlab.ui.control.Label
        FxFalsaPosicionTextArea         matlab.ui.control.TextArea
        FxLabel_2                       matlab.ui.control.Label
        MtododeFalsaPosicinLabel        matlab.ui.control.Label
        UIAxesFalsaPosicion             matlab.ui.control.UIAxes
        NewtonRaphsonTab                matlab.ui.container.Tab
        CriteriodeparadaLabel_3         matlab.ui.control.Label
        DatosLabel_3                    matlab.ui.control.Label
        MtododeNewtonRaphsonLabel       matlab.ui.control.Label
        LabelNewtonRaphson              matlab.ui.control.Label
        NIteracionesNewtonRaphsonTextArea  matlab.ui.control.TextArea
        NIteracionesLabel_2             matlab.ui.control.Label
        UITableNewtonRaphson            matlab.ui.control.Table
        ComputarNewtonRaphsonButton     matlab.ui.control.Button
        XoNewtonRaphsonTextArea         matlab.ui.control.TextArea
        XoLabel                         matlab.ui.control.Label
        FxprimaNewtonRaphsonTextArea    matlab.ui.control.TextArea
        FxLabel_4                       matlab.ui.control.Label
        FxNewtonRaphsonTextArea         matlab.ui.control.TextArea
        FxTextArea_4Label_2             matlab.ui.control.Label
        UIAxesNewtonRaphson             matlab.ui.control.UIAxes
        PuntoFijoTab                    matlab.ui.container.Tab
        CriteriodeparadaLabel_4         matlab.ui.control.Label
        DatosLabel_4                    matlab.ui.control.Label
        MtododePuntoFijoLabel           matlab.ui.control.Label
        LabelCriterioParadaPuntoFijo    matlab.ui.control.Label
        NIteracionesPuntoFijoTextArea   matlab.ui.control.TextArea
        NIteracionesLabel               matlab.ui.control.Label
        UITablePuntoFijo                matlab.ui.control.Table
        XoPuntoFijoTextArea             matlab.ui.control.TextArea
        XoLabel_2                       matlab.ui.control.Label
        ComputarPuntoFijo               matlab.ui.control.Button
        GxPuntoFijoTextArea             matlab.ui.control.TextArea
        GxLabel                         matlab.ui.control.Label
        FXLabel                         matlab.ui.control.Label
        FXPuntoFijoTextArea             matlab.ui.control.TextArea
        UIAxesPuntoFijo                 matlab.ui.control.UIAxes
        DerivadasTab                    matlab.ui.container.Tab
        DatosLabel_5                    matlab.ui.control.Label
        MtododelasDerivadasdeAltaPresicinLabel_2  matlab.ui.control.Label
        TercerDerivadaLabel             matlab.ui.control.Label
        SegundaDerivadaLabel            matlab.ui.control.Label
        PrimeraDerivadaLabel            matlab.ui.control.Label
        FuncionOriginalLabel            matlab.ui.control.Label
        LaterceraderivadaesLabel        matlab.ui.control.Label
        LasegundaderivadaesLabel        matlab.ui.control.Label
        LaprimeraderivadaesLabel        matlab.ui.control.Label
        LafuncinoriginalesLabel         matlab.ui.control.Label
        ComputarDerivadasButton         matlab.ui.control.Button
        HDerivadasTextArea              matlab.ui.control.TextArea
        HLabel                          matlab.ui.control.Label
        XiDerivadasTextArea             matlab.ui.control.TextArea
        XiLabel_2                       matlab.ui.control.Label
        FxDerivadasTextArea             matlab.ui.control.TextArea
        FxTextArea_2Label_2             matlab.ui.control.Label
        TrapeciomltipleTab              matlab.ui.container.Tab
        DatosLabel_6                    matlab.ui.control.Label
        MtododelTrapecioMltipleLabel_2  matlab.ui.control.Label
        LabelResultadoTrapecioMultiple  matlab.ui.control.Label
        ElresultadodelaintegralresueltaporeltrapeciomltipleesdeLabel  matlab.ui.control.Label
        UITableFXiTrapecioMultiple      matlab.ui.control.Table
        UITableXiTrapecioMultiple       matlab.ui.control.Table
        ComputarTrapecioMultipleButton  matlab.ui.control.Button
        nTrapecioMultipleTextArea       matlab.ui.control.TextArea
        nLabel                          matlab.ui.control.Label
        LimitesuperiorTrapecioMultipleTextArea  matlab.ui.control.TextArea
        LimitesuperiorLabel             matlab.ui.control.Label
        LimiteinferiorTrapecioMultipleTextArea  matlab.ui.control.TextArea
        LimiteinferiorLabel             matlab.ui.control.Label
        FxTrapecioMultipleTextArea      matlab.ui.control.TextArea
        FxLabel_3                       matlab.ui.control.Label
        Simpson13multipleTab            matlab.ui.container.Tab
        DatosLabel_7                    matlab.ui.control.Label
        MtododeSimpson13MltipleLabel_2  matlab.ui.control.Label
        LabelResultadoSimpson13Multiple  matlab.ui.control.Label
        Elresultadodelaintegralporelmtododesimpson13mltipleesLabel  matlab.ui.control.Label
        UITableFXiSimpson13Multiple     matlab.ui.control.Table
        UITableXiSimpson13Multiple      matlab.ui.control.Table
        Computar13MultipleButton        matlab.ui.control.Button
        nSimpson13MultipleTextArea      matlab.ui.control.TextArea
        nLabel_2                        matlab.ui.control.Label
        LimitesuperiorSimpson13MultipleTextArea  matlab.ui.control.TextArea
        LimitesuperiorLabel_2           matlab.ui.control.Label
        LimiteinferiorSimpson13MultipleTextArea  matlab.ui.control.TextArea
        LimiteinferiorLabel_2           matlab.ui.control.Label
        FxSimpson13MultipleTextArea     matlab.ui.control.TextArea
        FxTextArea_3Label_2             matlab.ui.control.Label
        Simpson38mltipleTab             matlab.ui.container.Tab
        DatosLabel_8                    matlab.ui.control.Label
        MtododeSimpson38MltipleLabel_2  matlab.ui.control.Label
        LabelResultadoSimpson38Multiple  matlab.ui.control.Label
        Elresultadodelaintegralporelmtododesimpson38mltipleesLabel  matlab.ui.control.Label
        UITableFXiSimpson38Multiple     matlab.ui.control.Table
        UITableXiSimpson38Multiple      matlab.ui.control.Table
        ComputarSimpson38MultipleButton  matlab.ui.control.Button
        nSimpson38MultipleTextArea      matlab.ui.control.TextArea
        nLabel_3                        matlab.ui.control.Label
        LimitesuperiorSimpson38MultipleTextArea  matlab.ui.control.TextArea
        LimitesuperiorLabel_3           matlab.ui.control.Label
        LimiteinferiorSimpson38MultipleTextArea  matlab.ui.control.TextArea
        LimiteinferiorLabel_3           matlab.ui.control.Label
        FxSimpson38MultipleTextArea     matlab.ui.control.TextArea
        FxTextArea_3Label_3             matlab.ui.control.Label
        RungeKutta3Tab                  matlab.ui.container.Tab
        MtodoRungeKutta3Label           matlab.ui.control.Label
        UITable_10                      matlab.ui.control.Table
        ValorincrementohEditField_10    matlab.ui.control.NumericEditField
        ValorincrementohLabel           matlab.ui.control.Label
        FuncionxyEditField_10           matlab.ui.control.EditField
        FuncionxyLabel                  matlab.ui.control.Label
        ValorXoEditField_10             matlab.ui.control.NumericEditField
        ValorXoLabel                    matlab.ui.control.Label
        ValorYoEditField_10             matlab.ui.control.NumericEditField
        ValorYoEditFieldLabel           matlab.ui.control.Label
        ValorallegarxEditField_10       matlab.ui.control.NumericEditField
        ValorallegarxEditFieldLabel     matlab.ui.control.Label
        CalcularButton_10               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            % Método de la bisección
            % Se obtienen los valores ingresados por el usuario
            xi= str2double(app.XiTextArea.Value);
            xs= str2double(app.XsTextArea.Value);
            iter= str2double(app.NIteracionesTextArea.Value);
            fx = str2func("@(x)"+app.FxTextArea.Value);
            e= 0;

            % Se inicia el ciclo que es el encargado de realizar el método

            for k=1:iter

                xr=(xi+xs)/2;

                % Se crea una matriz para ir guardando los valores que se
                % van obteniendo con el fin de mostrarlos en la tabla

                A(k,:)=[k xi xr xs fx(xi) fx(xr) fx(xs) e];

                % Se valida el cambio de signo

                if fx(xi)*fx(xr)<0
                    xs=xr;
                else
                    xi=xr;
                end

                % Esta condición se crea para registrar el error desde la
                % segunda iteración

                if(k>=2)
                    e = abs((A(k,3)- A(k-1,3))/A(k,3))*100;
                    A(k,8)=e;
                end
            end

            % Utilizamos esta linea para mostrar los datos en la tabla
            app.UITable.Data = A;

            % Con esta linea mostramos la gráfica en nuestra Axes
            ezplot(app.UIAxes, fx);
            app.LabelCriterioParadaBiseccion.Text = string("Ea < " + (A(iter,8)+0.1));
        end

        % Button pushed function: ComputarPuntoFijo
        function ComputarPuntoFijoButtonPushed(app, event)
            % Capturamos los valores ingresados por el usuario

            gx = str2func("@(x)"+ app.GxPuntoFijoTextArea.Value);
            fx = str2func("@(x)"+ app.FXPuntoFijoTextArea.Value);
            xi = str2double(app.XoPuntoFijoTextArea.Value);
            iteracion = str2double(app.NIteracionesPuntoFijoTextArea.Value);
            e = 0.0;

            % Se valida primeramente que gx converja a menor a 1

            if(gx(xi) < 1)

                % Iniciamos el ciclo para hallar los valores

                for i=1:iteracion

                    % Se crea una matriz para ir guardando los valores

                    A(i,:)=[i xi gx(xi) fx(xi) e];

                    xi=gx(xi);

                    % Esta condición se crea para registrar el error desde la
                    % segunda iteración

                    if(i>=2)
                        e = abs((A(i,3)- A(i-1,3))/A(i,3))*100;
                        A(i,5)=e;
                    end
                end

                % Con esta linea mostramos en la tabla
                app.UITablePuntoFijo.Data = A;

                % Con esta linea mostramos en la gráfica de la interfaz
                ezplot(app.UIAxesPuntoFijo,fx);
                app.LabelCriterioParadaPuntoFijo.Text = string("Ea < " + (A(iteracion,5)+0.1));
            else
                uialert(app.UIFigure,'la función gprima(x) no es convergente. Ingrese una distinta','Invalid File');
            end
        end

        % Button pushed function: ComputarFalsaPosicionButton
        function ComputarFalsaPosicionButtonPushed(app, event)
             % Capturamos los datos ingresados en la interfaz

            fx = str2func("@(x)"+ app.FxFalsaPosicionTextArea.Value);
            xi = str2double(app.XiFalsaPosicionTextArea.Value);
            xs = str2double(app.XsFalsaPosicionTextArea.Value);
            iteracion = str2double(app.NIteracionesFalsaPosicionTextArea.Value);
            e = 0.0;

            % Iniciamos el ciclo para hallar los valores

            for k=1 : iteracion

                % Calculamos xr
                xr=(xs*fx(xi)-xi*fx(xs))/(fx(xi)-fx(xs));

                % Creamos la matriz para guardar los valores
                A(k,:)=[k xi xr xs fx(xi) fx(xr) fx(xs) e];

                % Asignamos el valor de xr a xi
                xi = xr;

                % Esta condición se crea para registrar el error desde la
                % segunda iteración

                if(k>=2)
                    e = abs((A(k,3)- A(k-1,3))/A(k,3))*100;
                    A(k,8) = e;
                end
            end

            % Mostramos en la tabla la información y creamos la gráfica
            app.UITableFalsaPosicion.Data = A;
            ezplot(app.UIAxesFalsaPosicion,fx);
            app.LabelCriterioParadaFalsaPosicion.Text = string("Ea < " + (A(iteracion,8)+0.1));
        end

        % Button pushed function: ComputarDerivadasButton
        function ComputarDerivadasButtonPushed(app, event)
            % Capturamos los valores ingresados por el usuario en la
            % interfaz

            fx = str2func("@(x)"+ app.FxDerivadasTextArea.Value);
            xi = str2double(app.XiDerivadasTextArea.Value);
            h = str2double(app.HDerivadasTextArea.Value);

            % Se muestra la funcion original en el label asignado de la
            % interfaz
            app.FuncionOriginalLabel.Text = app.FxDerivadasTextArea.Value;

            % Creamos un arreglo con los xi que vamos a necesitar
            E=[-3 -2 -1 0 1 2 3];
            size=length(E);

            primera=0;
            segunda=0;
            tercera=0;

            % Se inicia el ciclo del método

            for a=1:size
                Fx(a) = feval(fx, (xi - E(a)*h) );
            end
            signo = -1;
            % primera derivada
            for j=1:(size-2)
                if j == round(((size-2)/2), 0)
                    continue
                end
                if mod(j,2) ~= 0
                    primera = primera + (signo*Fx(j+1));
                else
                    primera = primera + (signo*8*Fx(j+1));
                end
                signo = signo * -1;
            end

            % Segunda derivada
            for j=1:(size-2)
                if j == 1
                    %f(xi+2)
                    segunda = segunda - (Fx(j+1));
                elseif j == (size-2)
                    %f(xi-2)
                    segunda = segunda - (Fx(j+1));
                elseif j == round(((size-2)/2), 0) % round(((size-2)/2), 0) = 3
                    %f(xi)
                    segunda = segunda - (30*Fx(j+1));
                elseif mod(j,2) == 0
                    %f(xi+1) y f(xi-1)
                    segunda = segunda + (16*Fx(j+1));
                end
            end

            % Tercera Derivada
            for j=1:(size)
                if j == 1
                    %f(xi+3)
                    tercera = tercera + (signo*Fx(j));
                elseif j == 2
                    %f(xi+2)
                    tercera = tercera + (signo*8*Fx(j));
                elseif j == 3
                    %f(xi+1)
                    tercera = tercera + (signo*13*Fx(j));
                elseif j == round((size/2), 0) %round((size)/2), 0) = 4
                    %f(xi) no se suma
                    continue
                elseif j == 5
                    %f(xi-1)
                    tercera = tercera + (signo*13*Fx(j));
                elseif j == 6
                    %f(xi-2)
                    tercera = tercera + (signo*8*Fx(j));
                elseif j == size
                    %f(xi-3)
                    tercera = tercera + (signo*Fx(j));
                end
                signo = signo * -1;
            end

            % Lo dividimos en los valores dados por las fórmulas

            primera = primera / (12*h);
            segunda = segunda / (12*h^2);
            tercera = tercera / (8*h^3);

            % Mostramos en los labels la información encontrada

            app.PrimeraDerivadaLabel.Text =  string(primera);
            app.SegundaDerivadaLabel.Text = string(segunda);
            app.TercerDerivadaLabel.Text = string(tercera);
        end

        % Button pushed function: ComputarTrapecioMultipleButton
        function ComputarTrapecioMultipleButtonPushed(app, event)
            % Capturamos la información ingresada por el usuario en la
            % interfaz

            fx = str2func("@(x)"+ app.FxTrapecioMultipleTextArea.Value);
            a = str2double(app.LimiteinferiorTrapecioMultipleTextArea.Value);
            b = str2double(app.LimitesuperiorTrapecioMultipleTextArea.Value);
            n = str2double(app.nTrapecioMultipleTextArea.Value);
            h = (b-a)/n;
            suma = 0.0;

            % Capturamos los Xi en la matriz definida

            for k=0:n
                xi = a + k*h;
                A(k+1, :) = [k xi];
            end

            % Capturamos los F(xi) de cada Xi

            for j=0:n
                fxi= fx(A(j+1,2));
                B(j+1, :) = [j fxi];
            end

            % Mostramos la información en las tablas

            app.UITableXiTrapecioMultiple.Data = A;
            app.UITableFXiTrapecioMultiple.Data = B;

            % Sumamos los valores de n-1

            for l=1:n-1
                suma = suma + B(l+1,2);
            end

            % Calculamos el valor de la integral

            integral = (h/2)*(B(1,2)+(2*suma)+ B(n,2));

            % Mostramos el valor en el label designado para la interfaz

            app.LabelResultadoTrapecioMultiple.Text = string(integral);
        end

        % Button pushed function: Computar13MultipleButton
        function ComputarSimpson13MultipleButtonPushed(app, event)
            % se obtienen los valores ingresados por el usuario
            fx = str2func("@(x)"+ app.FxSimpson13MultipleTextArea.Value);
            a = str2double(app.LimiteinferiorSimpson13MultipleTextArea.Value);
            b = str2double(app.LimitesuperiorSimpson13MultipleTextArea.Value);
            n = str2double(app.nSimpson13MultipleTextArea.Value);
            h = (b - a) / n;
            sumaImpar = 0;
            sumaPar = 0;

            % Agregamos los valores iniciales a nuestras 2 matrices
            A(1, :) = [0 a];
            B(1, :) = [A(1,2) fx(a)];

            % Se realizan las operaciones para la sumatoria de impares
            for i=1:2: n - 1
                A(i+1, :) = [i (h * i + a)];
                B(i+1, :) = [A(i+1,2) fx(A(i+1,2))];
                sumaImpar = sumaImpar + fx(A(i+1,2));
            end

            % Se realizan las operaciones para la sumatoria pares
            for i=2:2: n - 2
                A(i+1, :) = [i (h * i + a)];
                B(i+1, :) = [A(i+1,2) fx(A(i+1,2))];
                sumaPar = sumaPar + fx(A(i+1,2));
            end

            % Guardamos los valores finales en cada matriz
            A(n+1, :) = [n b];
            B(n+1, :) = [A(i+1,2) fx(A(n+1, 2))];

            % Se aplica la formula y se muestra en el label designado
            % en la interfaz
            app.LabelResultadoSimpson13Multiple.Text = single(((b - a) / (3*n)) * (feval( fx, a) + 4*sumaImpar + 2*sumaPar + feval(fx,b))) + "" ;

            % Mostramos la información en las tablas
            app.UITableXiSimpson13Multiple.Data = A;
            app.UITableFXiSimpson13Multiple.Data = B;
        end

        % Button pushed function: ComputarSimpson38MultipleButton
        function ComputarSimpson38MultipleButtonPushed(app, event)
            % se obtienen los valores ingresados por el usuario
            fx = str2func("@(x)"+app.FxSimpson38MultipleTextArea.Value);
            a = str2double(app.LimiteinferiorSimpson38MultipleTextArea.Value);
            b = str2double(app.LimitesuperiorSimpson38MultipleTextArea.Value);
            n = str2double(app.nSimpson38MultipleTextArea.Value);
            h = (b - a) / n;
            sumaNoMult3 = 0;
            sumaMult3 = 0;

            % Agregamos los valores iniciales a cada matriz
            A(1, :) = [0 a];
            B(1, :) = [A(1,2) fx(a)];

            % Se realiza las sumatorias y se guardan los valores en
            % cada matriz
            for i=1:n-1
                A(i+1, :) = [i (h * i + a)];
                B(i+1, :) = [A(i+1,2) fx(A(i+1,2))];

                if mod(i,3)~=0
                    sumaNoMult3 = sumaNoMult3 + fx(A(i+1,2));
                else
                    sumaMult3 = sumaMult3 + fx(A(i+1,2));
                end
            end

            % Se guardan los valores finales en cada matriz
            A(n+1, :) = [n b];
            B(n+1, :) = [A(i+1,2) fx(A(n+1, 2))];

            % Se aplica la fórmula y se muestra en el label designado
            % en la interfaz
            app.LabelResultadoSimpson38Multiple.Text = single((((3*h)/8) * (feval(fx,a) + 3*sumaNoMult3 + 2*sumaMult3 + feval(fx,b)))) + "" ;

            % Se mostra la información en la tabla
            app.UITableXiSimpson38Multiple.Data = A;
            app.UITableFXiSimpson38Multiple.Data = B;
        end

        % Button pushed function: ComputarNewtonRaphsonButton
        function ComputarNewtonRaphsonButtonPushed(app, event)
            % Capturamos la información ingresada en la interfaz
            fx = str2func("@(x)"+ app.FxNewtonRaphsonTextArea.Value);
            fxprima = str2func("@(x)"+ app.FxprimaNewtonRaphsonTextArea.Value);
            xi = str2double(app.XoNewtonRaphsonTextArea.Value);
            iteraciones = str2double(app.NIteracionesNewtonRaphsonTextArea.Value);
            ximas1 = 0.0;
            e = 0.0;

            % Iniciamos el ciclo para el método
            for k=1 : iteraciones
                ximas1 = xi - (fx(xi)/fxprima(xi));

                % Guardamos la información en la matriz
                A(k, :) = [k ximas1 xi fx(xi) fxprima(xi) e];

                xi = ximas1;

                % Esta condición se crea para registrar el error desde la
                % segunda iteración
                if(k>=2)
                    e = abs((A(k,3)- A(k-1,3))/A(k,3))*100;
                    A(k,6) = e;
                end
            end

            % Mostramos la información en la tabla y creamos la gráfica
            app.UITableNewtonRaphson.Data = A;
            ezplot(app.UIAxesNewtonRaphson, fx);
            app.LabelNewtonRaphson.Text = string("Ea < " + (A(iteraciones,6)+0.1));
        end

        % Callback function: CalcularButton_10, RungeKutta3Tab
        function CalcularRungeKutta(app, event)
            m= app.FuncionxyEditField_10.Value;
             %fx = str2func("@(x)"+ app.FxNewtonRaphsonTextArea.Value);
            f=inline(m,'x','y');
            
            x=app.ValorXoEditField_10.Value;
            y=app.ValorYoEditField_10.Value;
            h=app.ValorincrementohEditField_10.Value;
            val=app.ValorallegarxEditField_10.Value;
            
            itr=(val-x)/h;
            n=0.0;
            
            for i=1:itr+1
                l=x;
                p=y;
                x=x+(0.50*h);
                k1=f(l,p);
                y=p+(0.50*k1*h);
                k2=f(x,y);
                y=p-(k1*h)+(2*k2*h);
                x=l+h;
                k3=f(x,y);
                fr=(k1/6)+(4*k2/6)+(k3/6);
                y=p+(h*fr);
                %fprintf('%2.0f %13.4f %13.4f %13.4f %17.4f\n',n,l,p,y,fr);
                M(i,:)=[n  l  p  y  fr];
                n=n+1;
            end
            app.UITable_10.Data= M;
            %disp(M);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BiseccinTab = uitab(app.TabGroup);
            app.BiseccinTab.Title = 'Bisección';
            app.UIAxes = uiaxes(app.BiseccinTab);
            title(app.UIAxes, 'Gráfica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MtododeBiseccinLabel = uilabel(app.BiseccinTab);
            app.MtododeBiseccinLabel.Text = 'Método de Bisección';
            app.DatosLabel = uilabel(app.BiseccinTab);
            app.DatosLabel.Text = 'Datos ';
            app.FxLabel = uilabel(app.BiseccinTab);
            app.FxLabel.Text = 'F(x)';
            app.FxTextArea = uitextarea(app.BiseccinTab);
            app.FxTextArea.Tag = 'fxBiseccion';
            app.IntervaloInferiorLabel = uilabel(app.BiseccinTab);
            app.IntervaloInferiorLabel.Text = 'Xi';
            app.XiTextArea = uitextarea(app.BiseccinTab);
            app.IntervaloSuperiorLabel = uilabel(app.BiseccinTab);
            app.IntervaloSuperiorLabel.Text = 'Xs';
            app.XsTextArea = uitextarea(app.BiseccinTab);
            app.IteracionesLabel = uilabel(app.BiseccinTab);
            app.IteracionesLabel.Text = 'N° Iteraciones ';
            app.NIteracionesTextArea = uitextarea(app.BiseccinTab);
            app.UITable = uitable(app.BiseccinTab);
            app.UITable.ColumnName = {'Iteración'; 'Xi'; 'Xr'; 'Xs'; 'F(xi)'; 'F(xr)'; 'F(xs)'; 'e'};
            app.UITable.RowName = {};
            app.CalcularButton_2 = uibutton(app.BiseccinTab, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Icon = fullfile(pathToMLAPP, 'Calculator.png');
            app.CalcularButton_2.Text = 'Calcular';
            app.CriteriodeparadaLabel = uilabel(app.BiseccinTab);
            app.CriteriodeparadaLabel.Text = 'Criterio de parada ';
            app.LabelCriterioParadaBiseccion = uilabel(app.BiseccinTab);
            app.LabelCriterioParadaBiseccion.Text = '';
            app.FalsaPosicionTab = uitab(app.TabGroup);
            app.FalsaPosicionTab.Title = 'Falsa Posición';
            app.UIAxesFalsaPosicion = uiaxes(app.FalsaPosicionTab);
            title(app.UIAxesFalsaPosicion, 'Gráfica')
            xlabel(app.UIAxesFalsaPosicion, 'X')
            ylabel(app.UIAxesFalsaPosicion, 'Y')
            zlabel(app.UIAxesFalsaPosicion, 'Z')
            app.MtododeFalsaPosicinLabel = uilabel(app.FalsaPosicionTab);
            app.MtododeFalsaPosicinLabel.Text = 'Método de Falsa Posición';
            app.FxLabel_2 = uilabel(app.FalsaPosicionTab);
            app.FxLabel_2.Text = 'F(x) ';
            app.FxFalsaPosicionTextArea = uitextarea(app.FalsaPosicionTab);
            app.XiLabel = uilabel(app.FalsaPosicionTab);
            app.XiLabel.Text = 'Xi ';
            app.XiFalsaPosicionTextArea = uitextarea(app.FalsaPosicionTab);
            app.XsLabel = uilabel(app.FalsaPosicionTab);
            app.XsLabel.Text = 'Xs ';
            app.XsFalsaPosicionTextArea = uitextarea(app.FalsaPosicionTab);
            app.NIteracionesTextArea_2Label_2 = uilabel(app.FalsaPosicionTab);
            app.NIteracionesTextArea_2Label_2.Text = 'N° Iteraciones ';
            app.NIteracionesFalsaPosicionTextArea = uitextarea(app.FalsaPosicionTab);
            app.ComputarFalsaPosicionButton = uibutton(app.FalsaPosicionTab, 'push');
            app.ComputarFalsaPosicionButton.ButtonPushedFcn = createCallbackFcn(app, @ComputarFalsaPosicionButtonPushed, true);
            app.ComputarFalsaPosicionButton.Icon = fullfile(pathToMLAPP, 'Calculator.png');
            app.ComputarFalsaPosicionButton.Text = 'Calcular';
            app.UITableFalsaPosicion = uitable(app.FalsaPosicionTab);
            app.UITableFalsaPosicion.ColumnName = {'Iteracion'; 'Xi'; 'Xr'; 'Xs'; 'F(xi)'; 'F(xr)'; 'F(xs)'; 'e'};
            app.UITableFalsaPosicion.RowName = {};
            app.LabelCriterioParadaFalsaPosicion = uilabel(app.FalsaPosicionTab);
            app.LabelCriterioParadaFalsaPosicion.Text = '';
            app.DatosLabel_2 = uilabel(app.FalsaPosicionTab);
            app.DatosLabel_2.Text = 'Datos ';
            app.CriteriodeparadaLabel_2 = uilabel(app.FalsaPosicionTab);
            app.CriteriodeparadaLabel_2.Text = 'Criterio de parada ';
            app.NewtonRaphsonTab = uitab(app.TabGroup);
            app.NewtonRaphsonTab.Title = 'Newton Raphson';
            app.UIAxesNewtonRaphson = uiaxes(app.NewtonRaphsonTab);
            title(app.UIAxesNewtonRaphson, 'Gráfica')
            xlabel(app.UIAxesNewtonRaphson, 'X')
            ylabel(app.UIAxesNewtonRaphson, 'Y')
            zlabel(app.UIAxesNewtonRaphson, 'Z')
            app.FxTextArea_4Label_2 = uilabel(app.NewtonRaphsonTab);
            app.FxTextArea_4Label_2.Text = 'F(x) ';
            app.FxNewtonRaphsonTextArea = uitextarea(app.NewtonRaphsonTab);
            app.FxLabel_4 = uilabel(app.NewtonRaphsonTab);
            app.FxLabel_4.Text = 'F''(x) ';
            app.FxprimaNewtonRaphsonTextArea = uitextarea(app.NewtonRaphsonTab);
            app.XoLabel = uilabel(app.NewtonRaphsonTab);
            app.XoLabel.Text = 'Xo ';
            app.XoNewtonRaphsonTextArea = uitextarea(app.NewtonRaphsonTab);
            app.ComputarNewtonRaphsonButton = uibutton(app.NewtonRaphsonTab, 'push');
            app.ComputarNewtonRaphsonButton.ButtonPushedFcn = createCallbackFcn(app, @ComputarNewtonRaphsonButtonPushed, true);
            app.ComputarNewtonRaphsonButton.Icon = fullfile(pathToMLAPP, 'Calculator.png');
            app.ComputarNewtonRaphsonButton.Text = 'Calcular';
            app.UITableNewtonRaphson = uitable(app.NewtonRaphsonTab);
            app.UITableNewtonRaphson.ColumnName = {'Iteracion'; 'Xi+1'; 'Xi'; 'F(Xi)'; 'F''(Xi)'; 'e'};
            app.UITableNewtonRaphson.RowName = {};
            app.NIteracionesLabel_2 = uilabel(app.NewtonRaphsonTab);
            app.NIteracionesLabel_2.Text = 'N° Iteraciones ';
            app.NIteracionesNewtonRaphsonTextArea = uitextarea(app.NewtonRaphsonTab);
            app.LabelNewtonRaphson = uilabel(app.NewtonRaphsonTab);
            app.LabelNewtonRaphson.Text = '';
            app.MtododeNewtonRaphsonLabel = uilabel(app.NewtonRaphsonTab);
            app.MtododeNewtonRaphsonLabel.Text = 'Método de Newton Raphson';
            app.DatosLabel_3 = uilabel(app.NewtonRaphsonTab);
            app.DatosLabel_3.Text = 'Datos ';
            app.CriteriodeparadaLabel_3 = uilabel(app.NewtonRaphsonTab);
            app.CriteriodeparadaLabel_3.Text = 'Criterio de parada ';
            app.PuntoFijoTab = uitab(app.TabGroup);
            app.PuntoFijoTab.Title = 'Punto Fijo';
            app.UIAxesPuntoFijo = uiaxes(app.PuntoFijoTab);
            title(app.UIAxesPuntoFijo, 'Gráfica')
            xlabel(app.UIAxesPuntoFijo, 'X')
            ylabel(app.UIAxesPuntoFijo, 'Y')
            zlabel(app.UIAxesPuntoFijo, 'Z')
            app.FXPuntoFijoTextArea = uitextarea(app.PuntoFijoTab);
            app.FXLabel = uilabel(app.PuntoFijoTab);
            app.FXLabel.Text = 'F(X) ';
            app.GxLabel = uilabel(app.PuntoFijoTab);
            app.GxLabel.Text = 'G''(x) ';
            app.GxPuntoFijoTextArea = uitextarea(app.PuntoFijoTab);
            app.ComputarPuntoFijo = uibutton(app.PuntoFijoTab, 'push');
            app.ComputarPuntoFijo.ButtonPushedFcn = createCallbackFcn(app, @ComputarPuntoFijoButtonPushed, true);
            app.ComputarPuntoFijo.Icon = fullfile(pathToMLAPP, 'Calculator.png');
            app.ComputarPuntoFijo.Text = 'Calcular';
            app.XoLabel_2 = uilabel(app.PuntoFijoTab);
            app.XoLabel_2.Text = 'Xo ';
            app.XoPuntoFijoTextArea = uitextarea(app.PuntoFijoTab);
            app.UITablePuntoFijo = uitable(app.PuntoFijoTab);
            app.UITablePuntoFijo.ColumnName = {'Iteración'; 'Xi'; 'g''(xi)'; 'f(xi)'; 'e'};
            app.UITablePuntoFijo.RowName = {};
            app.NIteracionesLabel = uilabel(app.PuntoFijoTab);
            app.NIteracionesLabel.Text = 'N° Iteraciones ';
            app.NIteracionesPuntoFijoTextArea = uitextarea(app.PuntoFijoTab);
            app.LabelCriterioParadaPuntoFijo = uilabel(app.PuntoFijoTab);
            app.LabelCriterioParadaPuntoFijo.Text = '';
            app.MtododePuntoFijoLabel = uilabel(app.PuntoFijoTab);
            app.MtododePuntoFijoLabel.Text = 'Método de Punto Fijo';
            app.DatosLabel_4 = uilabel(app.PuntoFijoTab);
            app.DatosLabel_4.Text = 'Datos ';
            app.CriteriodeparadaLabel_4 = uilabel(app.PuntoFijoTab);
            app.CriteriodeparadaLabel_4.Text = 'Criterio de parada ';
            app.DerivadasTab = uitab(app.TabGroup);
            app.DerivadasTab.Title = 'Derivadas';
            app.FxTextArea_2Label_2 = uilabel(app.DerivadasTab);
            app.FxTextArea_2Label_2.Text = 'F(x) ';
            app.FxDerivadasTextArea = uitextarea(app.DerivadasTab);
            app.XiLabel_2 = uilabel(app.DerivadasTab);
            app.XiLabel_2.Text = 'Xi ';
            app.XiDerivadasTextArea = uitextarea(app.DerivadasTab);
            app.HLabel = uilabel(app.DerivadasTab);
            app.HLabel.Text = 'H ';
            app.HDerivadasTextArea = uitextarea(app.DerivadasTab);
            app.ComputarDerivadasButton = uibutton(app.DerivadasTab, 'push');
            app.ComputarDerivadasButton.ButtonPushedFcn = createCallbackFcn(app, @ComputarDerivadasButtonPushed, true);
            app.ComputarDerivadasButton.Icon = fullfile(pathToMLAPP, 'Calculator.png');
            app.ComputarDerivadasButton.Text = 'Calcular';
            app.LafuncinoriginalesLabel = uilabel(app.DerivadasTab);
            app.LafuncinoriginalesLabel.Text = 'La función original es:';
            app.LaprimeraderivadaesLabel = uilabel(app.DerivadasTab);
            app.LaprimeraderivadaesLabel.Text = 'La primera derivada es:';
            app.LasegundaderivadaesLabel = uilabel(app.DerivadasTab);
            app.LasegundaderivadaesLabel.Text = 'La segunda derivada es:';
            app.LaterceraderivadaesLabel = uilabel(app.DerivadasTab);
            app.LaterceraderivadaesLabel.Text = 'La tercera derivada es:';
            app.FuncionOriginalLabel = uilabel(app.DerivadasTab);
            app.FuncionOriginalLabel.Text = 'Valor';
            app.PrimeraDerivadaLabel = uilabel(app.DerivadasTab);
            app.PrimeraDerivadaLabel.Text = 'Valor';
            app.SegundaDerivadaLabel = uilabel(app.DerivadasTab);
            app.SegundaDerivadaLabel.Text = 'Valor';
            app.TercerDerivadaLabel = uilabel(app.DerivadasTab);
            app.TercerDerivadaLabel.Text = 'Valor';
            app.MtododelasDerivadasdeAltaPresicinLabel_2 = uilabel(app.DerivadasTab);
            app.MtododelasDerivadasdeAltaPresicinLabel_2.Text = 'Método de las Derivadas de Alta Presición';
            app.DatosLabel_5 = uilabel(app.DerivadasTab);
            app.DatosLabel_5.Text = 'Datos ';
            app.TrapeciomltipleTab = uitab(app.TabGroup);
            app.TrapeciomltipleTab.Title = 'Trapecio múltiple';
            app.FxLabel_3 = uilabel(app.TrapeciomltipleTab);
            app.FxLabel_3.Text = 'F(x) ';
            app.FxTrapecioMultipleTextArea = uitextarea(app.TrapeciomltipleTab);
            app.LimiteinferiorLabel = uilabel(app.TrapeciomltipleTab);
            app.LimiteinferiorLabel.Text = 'Limite inferior ';
            app.LimiteinferiorTrapecioMultipleTextArea = uitextarea(app.TrapeciomltipleTab);
            app.LimitesuperiorLabel = uilabel(app.TrapeciomltipleTab);
            app.LimitesuperiorLabel.Text = 'Limite superior ';
            app.LimitesuperiorTrapecioMultipleTextArea = uitextarea(app.TrapeciomltipleTab);
            app.nLabel = uilabel(app.TrapeciomltipleTab);
            app.nLabel.Text = 'n ';
            app.nTrapecioMultipleTextArea = uitextarea(app.TrapeciomltipleTab);
            app.ComputarTrapecioMultipleButton = uibutton(app.TrapeciomltipleTab, 'push');
            app.ComputarTrapecioMultipleButton.ButtonPushedFcn = createCallbackFcn(app, @ComputarTrapecioMultipleButtonPushed, true);
            app.ComputarTrapecioMultipleButton.Icon = fullfile(pathToMLAPP, 'Calculator.png');
            app.ComputarTrapecioMultipleButton.Text = 'Calcular';
            app.UITableXiTrapecioMultiple = uitable(app.TrapeciomltipleTab);
            app.UITableXiTrapecioMultiple.ColumnName = {'Iteracion'; 'Xi'};
            app.UITableXiTrapecioMultiple.RowName = {};
            app.UITableFXiTrapecioMultiple = uitable(app.TrapeciomltipleTab);
            app.UITableFXiTrapecioMultiple.ColumnName = {'Iteracion'; 'F(Xi)'};
            app.UITableFXiTrapecioMultiple.RowName = {};
            app.ElresultadodelaintegralresueltaporeltrapeciomltipleesdeLabel = uilabel(app.TrapeciomltipleTab);
            app.ElresultadodelaintegralresueltaporeltrapeciomltipleesdeLabel.Text = 'El resultado de la integral resuelta por el trapecio múltiple es de';
            app.LabelResultadoTrapecioMultiple = uilabel(app.TrapeciomltipleTab);
            app.LabelResultadoTrapecioMultiple.Text = '';
            app.MtododelTrapecioMltipleLabel_2 = uilabel(app.TrapeciomltipleTab);
            app.MtododelTrapecioMltipleLabel_2.Text = 'Método del Trapecio Múltiple';
            app.DatosLabel_6 = uilabel(app.TrapeciomltipleTab);
            app.DatosLabel_6.Text = 'Datos ';
            app.Simpson13multipleTab = uitab(app.TabGroup);
            app.Simpson13multipleTab.Title = 'Simpson 1/3 múltiple';
            app.FxTextArea_3Label_2 = uilabel(app.Simpson13multipleTab);
            app.FxTextArea_3Label_2.Text = 'F(x) ';
            app.FxSimpson13MultipleTextArea = uitextarea(app.Simpson13multipleTab);
            app.LimiteinferiorLabel_2 = uilabel(app.Simpson13multipleTab);
            app.LimiteinferiorLabel_2.Text = 'Limite inferior ';
            app.LimiteinferiorSimpson13MultipleTextArea = uitextarea(app.Simpson13multipleTab);
            app.LimitesuperiorLabel_2 = uilabel(app.Simpson13multipleTab);
            app.LimitesuperiorLabel_2.Text = 'Limite superior ';
            app.LimitesuperiorSimpson13MultipleTextArea = uitextarea(app.Simpson13multipleTab);
            app.nLabel_2 = uilabel(app.Simpson13multipleTab);
            app.nLabel_2.Text = 'n ';
            app.nSimpson13MultipleTextArea = uitextarea(app.Simpson13multipleTab);
            app.Computar13MultipleButton = uibutton(app.Simpson13multipleTab, 'push');
            app.Computar13MultipleButton.ButtonPushedFcn = createCallbackFcn(app, @ComputarSimpson13MultipleButtonPushed, true);
            app.Computar13MultipleButton.Icon = fullfile(pathToMLAPP, 'Calculator.png');
            app.Computar13MultipleButton.Text = 'Calcular';
            app.UITableXiSimpson13Multiple = uitable(app.Simpson13multipleTab);
            app.UITableXiSimpson13Multiple.ColumnName = {'Iteracion'; 'Xi'};
            app.UITableXiSimpson13Multiple.RowName = {};
            app.UITableFXiSimpson13Multiple = uitable(app.Simpson13multipleTab);
            app.UITableFXiSimpson13Multiple.ColumnName = {'Xi'; 'F(Xi)'};
            app.UITableFXiSimpson13Multiple.RowName = {};
            app.Elresultadodelaintegralporelmtododesimpson13mltipleesLabel = uilabel(app.Simpson13multipleTab);
            app.Elresultadodelaintegralporelmtododesimpson13mltipleesLabel.Text = 'El resultado de la integral por el método de simpson 1/3 múltiple es';
            app.LabelResultadoSimpson13Multiple = uilabel(app.Simpson13multipleTab);
            app.LabelResultadoSimpson13Multiple.Text = '';
            app.MtododeSimpson13MltipleLabel_2 = uilabel(app.Simpson13multipleTab);
            app.MtododeSimpson13MltipleLabel_2.Text = 'Método de Simpson 1/3 Múltiple';
            app.DatosLabel_7 = uilabel(app.Simpson13multipleTab);
            app.DatosLabel_7.Text = 'Datos ';
            app.Simpson38mltipleTab = uitab(app.TabGroup);
            app.Simpson38mltipleTab.Title = 'Simpson 3/8 múltiple';
            app.FxTextArea_3Label_3 = uilabel(app.Simpson38mltipleTab);
            app.FxTextArea_3Label_3.Text = 'F(x) ';
            app.FxSimpson38MultipleTextArea = uitextarea(app.Simpson38mltipleTab);
            app.LimiteinferiorLabel_3 = uilabel(app.Simpson38mltipleTab);
            app.LimiteinferiorLabel_3.Text = 'Limite inferior ';
            app.LimiteinferiorSimpson38MultipleTextArea = uitextarea(app.Simpson38mltipleTab);
            app.LimitesuperiorLabel_3 = uilabel(app.Simpson38mltipleTab);
            app.LimitesuperiorLabel_3.Text = 'Limite superior ';
            app.LimitesuperiorSimpson38MultipleTextArea = uitextarea(app.Simpson38mltipleTab);
            app.nLabel_3 = uilabel(app.Simpson38mltipleTab);
            app.nLabel_3.Text = 'n ';
            app.nSimpson38MultipleTextArea = uitextarea(app.Simpson38mltipleTab);
            app.ComputarSimpson38MultipleButton = uibutton(app.Simpson38mltipleTab, 'push');
            app.ComputarSimpson38MultipleButton.ButtonPushedFcn = createCallbackFcn(app, @ComputarSimpson38MultipleButtonPushed, true);
            app.ComputarSimpson38MultipleButton.Icon = fullfile(pathToMLAPP, 'Calculator.png');
            app.ComputarSimpson38MultipleButton.Text = 'Calcular';
            app.UITableXiSimpson38Multiple = uitable(app.Simpson38mltipleTab);
            app.UITableXiSimpson38Multiple.ColumnName = {'Iteracion'; 'Xi'};
            app.UITableXiSimpson38Multiple.RowName = {};
            app.UITableFXiSimpson38Multiple = uitable(app.Simpson38mltipleTab);
            app.UITableFXiSimpson38Multiple.ColumnName = {'Xi'; 'F(Xi)'};
            app.UITableFXiSimpson38Multiple.RowName = {};
            app.Elresultadodelaintegralporelmtododesimpson38mltipleesLabel = uilabel(app.Simpson38mltipleTab);
            app.Elresultadodelaintegralporelmtododesimpson38mltipleesLabel.Text = 'El resultado de la integral por el método de simpson 3/8 múltiple es';
            app.LabelResultadoSimpson38Multiple = uilabel(app.Simpson38mltipleTab);
            app.LabelResultadoSimpson38Multiple.Text = '';
            app.MtododeSimpson38MltipleLabel_2 = uilabel(app.Simpson38mltipleTab);
            app.MtododeSimpson38MltipleLabel_2.Text = 'Método de Simpson 3/8 Múltiple';
            app.DatosLabel_8 = uilabel(app.Simpson38mltipleTab);
            app.DatosLabel_8.Text = 'Datos ';
            app.RungeKutta3Tab = uitab(app.TabGroup);
            app.RungeKutta3Tab.SizeChangedFcn = createCallbackFcn(app, @CalcularRungeKutta, true);
            app.RungeKutta3Tab.Title = 'Runge-Kutta3';
            app.CalcularButton_10 = uibutton(app.RungeKutta3Tab, 'push');
            app.CalcularButton_10.ButtonPushedFcn = createCallbackFcn(app, @CalcularRungeKutta, true);
            app.CalcularButton_10.Icon = fullfile(pathToMLAPP, 'Calculator.png');
            app.CalcularButton_10.Text = 'Calcular';
            app.ValorallegarxEditFieldLabel = uilabel(app.RungeKutta3Tab);
            app.ValorallegarxEditFieldLabel.Text = 'Valor a llegar x';
            app.ValorallegarxEditField_10 = uieditfield(app.RungeKutta3Tab, 'numeric');
            app.ValorYoEditFieldLabel = uilabel(app.RungeKutta3Tab);
            app.ValorYoEditFieldLabel.Text = 'Valor Yo';
            app.ValorYoEditField_10 = uieditfield(app.RungeKutta3Tab, 'numeric');
            app.ValorXoLabel = uilabel(app.RungeKutta3Tab);
            app.ValorXoLabel.Text = 'Valor Xo';
            app.ValorXoEditField_10 = uieditfield(app.RungeKutta3Tab, 'numeric');
            app.FuncionxyLabel = uilabel(app.RungeKutta3Tab);
            app.FuncionxyLabel.Text = 'Funcion (x,y)';
            app.FuncionxyEditField_10 = uieditfield(app.RungeKutta3Tab, 'text');
            app.ValorincrementohLabel = uilabel(app.RungeKutta3Tab);
            app.ValorincrementohLabel.Text = 'Valor incremento h';
            app.ValorincrementohEditField_10 = uieditfield(app.RungeKutta3Tab, 'numeric');
            app.UITable_10 = uitable(app.RungeKutta3Tab);
            app.UITable_10.ColumnName = {'Iteracion'; 'valor x'; 'valor y'; 'evaluado y'; 'valor pendiente'};
            app.UITable_10.RowName = {};
            app.UITable_10.ColumnSortable = [true false false false false];
            app.MtodoRungeKutta3Label = uilabel(app.RungeKutta3Tab);
            app.MtodoRungeKutta3Label.Text = 'Método Runge Kutta 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end